classdef GradeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GradeCalculatorPanel        matlab.ui.container.Panel
        LetterGradeTextAreaLabel_7  matlab.ui.control.Label
        LetterGradeTextAreaLabel_6  matlab.ui.control.Label
        LetterGradeTextAreaLabel_5  matlab.ui.control.Label
        LetterGradeTextAreaLabel_4  matlab.ui.control.Label
        LetterGradeTextAreaLabel_3  matlab.ui.control.Label
        LetterGradeTextAreaLabel_2  matlab.ui.control.Label
        LetterGradeTextArea         matlab.ui.control.TextArea
        LetterGradeTextAreaLabel    matlab.ui.control.Label
        OverallGradeEditField       matlab.ui.control.NumericEditField
        OverallGradeEditFieldLabel  matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        Grade3EditField             matlab.ui.control.NumericEditField
        Grade3EditFieldLabel        matlab.ui.control.Label
        Grade2EditField             matlab.ui.control.NumericEditField
        Grade2EditFieldLabel        matlab.ui.control.Label
        Grade1EditField             matlab.ui.control.NumericEditField
        Grade1EditFieldLabel        matlab.ui.control.Label
        Grade5EditField             matlab.ui.control.NumericEditField
        Grade5EditFieldLabel        matlab.ui.control.Label
        Grade4EditField             matlab.ui.control.NumericEditField
        Grade4EditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % this function finds and displays the average of all 5 grades 
            % entered, then uses an in-else statement to determine and 
            % display the letter grade
            app.OverallGradeEditField.Value = ((app.Grade1EditField.Value + ... 
                app.Grade2EditField.Value + app.Grade3EditField.Value + ...
                app.Grade4EditField.Value + app.Grade5EditField.Value) / 5);
            
            if app.OverallGradeEditField.Value <= 100 && ...
                    app.OverallGradeEditField.Value >= 90
                app.LetterGradeTextArea.Value = 'A';
            elseif app.OverallGradeEditField.Value < 90 && ...
                    app.OverallGradeEditField.Value >= 80
                app.LetterGradeTextArea.Value = 'B';
            elseif app.OverallGradeEditField.Value < 80 && ...
                    app.OverallGradeEditField.Value >= 70
                app.LetterGradeTextArea.Value = 'C';
            elseif app.OverallGradeEditField.Value < 70 && ...
                    app.OverallGradeEditField.Value >= 60
                app.LetterGradeTextArea.Value = 'D';
            else
                app.LetterGradeTextArea.Value = 'F';
            end
        end

        % Value changed function: LetterGradeTextArea
        function LetterGradeTextAreaValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GradeCalculatorPanel = uipanel(app.UIFigure);
            app.GradeCalculatorPanel.Title = 'GradeCalculator';
            app.Grade4EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade4EditFieldLabel.Text = 'Grade4';
            app.Grade4EditField = uieditfield(app.GradeCalculatorPanel, 'numeric');
            app.Grade4EditField.Limits = [0 100];
            app.Grade5EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade5EditFieldLabel.Text = 'Grade5';
            app.Grade5EditField = uieditfield(app.GradeCalculatorPanel, 'numeric');
            app.Grade5EditField.Limits = [0 100];
            app.Grade1EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade1EditFieldLabel.Text = 'Grade1';
            app.Grade1EditField = uieditfield(app.GradeCalculatorPanel, 'numeric');
            app.Grade1EditField.Limits = [0 100];
            app.Grade2EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade2EditFieldLabel.Text = 'Grade2';
            app.Grade2EditField = uieditfield(app.GradeCalculatorPanel, 'numeric');
            app.Grade2EditField.Limits = [0 100];
            app.Grade3EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade3EditFieldLabel.Text = 'Grade3';
            app.Grade3EditField = uieditfield(app.GradeCalculatorPanel, 'numeric');
            app.Grade3EditField.Limits = [0 100];
            app.CalculateButton = uibutton(app.GradeCalculatorPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OverallGradeEditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.OverallGradeEditFieldLabel.Text = 'OverallGrade';
            app.OverallGradeEditField = uieditfield(app.GradeCalculatorPanel, 'numeric');
            app.LetterGradeTextAreaLabel = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel.Text = 'LetterGrade';
            app.LetterGradeTextArea = uitextarea(app.GradeCalculatorPanel);
            app.LetterGradeTextArea.ValueChangedFcn = createCallbackFcn(app, @LetterGradeTextAreaValueChanged, true);
            app.LetterGradeTextAreaLabel_2 = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel_2.Text = '%';
            app.LetterGradeTextAreaLabel_3 = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel_3.Text = '/ 100';
            app.LetterGradeTextAreaLabel_4 = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel_4.Text = '/ 100';
            app.LetterGradeTextAreaLabel_5 = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel_5.Text = '/ 100';
            app.LetterGradeTextAreaLabel_6 = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel_6.Text = '/ 100';
            app.LetterGradeTextAreaLabel_7 = uilabel(app.GradeCalculatorPanel);
            app.LetterGradeTextAreaLabel_7.Text = '/ 100';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GradeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end